classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Image               matlab.ui.control.Image
        Flujo               matlab.ui.control.NumericEditField
        A2Label_6           matlab.ui.control.Label
        FMM                 matlab.ui.control.NumericEditField
        A2Label_5           matlab.ui.control.Label
        Relq                matlab.ui.control.NumericEditField
        A2Label_4           matlab.ui.control.Label
        Rel2                matlab.ui.control.NumericEditField
        A2Label_3           matlab.ui.control.Label
        Rel1                matlab.ui.control.NumericEditField
        A2Label_2           matlab.ui.control.Label
        A2                  matlab.ui.control.NumericEditField
        A2Label             matlab.ui.control.Label
        A1                  matlab.ui.control.NumericEditField
        A1Label             matlab.ui.control.Label
        Label               matlab.ui.control.Label
        lc2                 matlab.ui.control.NumericEditField
        lc2EditFieldLabel   matlab.ui.control.Label
        lc1                 matlab.ui.control.NumericEditField
        lc1EditFieldLabel   matlab.ui.control.Label
        Panel               matlab.ui.container.Panel
        ReluctanciasButton  matlab.ui.control.Button
        p                   matlab.ui.control.NumericEditField
        pLabel              matlab.ui.control.Label
        FlujoButton         matlab.ui.control.Button
        reasButton          matlab.ui.control.Button
        I                   matlab.ui.control.NumericEditField
        miurLabel_3         matlab.ui.control.Label
        N                   matlab.ui.control.NumericEditField
        miurLabel_2         matlab.ui.control.Label
        miur                matlab.ui.control.NumericEditField
        miurLabel           matlab.ui.control.Label
        RecorridosButton    matlab.ui.control.Button
        c                   matlab.ui.control.NumericEditField
        cEditFieldLabel     matlab.ui.control.Label
        b                   matlab.ui.control.NumericEditField
        bEditFieldLabel     matlab.ui.control.Label
        a                   matlab.ui.control.NumericEditField
        aEditFieldLabel     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: a
        function aValueChanged(app, event)
       
            
        end

        % Value changed function: b
        function bValueChanged(app, event)
           
            
        end

        % Value changed function: c
        function cValueChanged(app, event)
        
        end

        % Button pushed function: RecorridosButton
        function RecorridosButtonPushed(app, event)
            l1 = (0.5*(app.a.Value) + app.b.Value + app.c.Value + app.b.Value + app.c.Value + app.b.Value + 0.5*(app.a.Value))/100;
            
            l2 = (0.5*app.c.Value + app.b.Value + 0.5*app.c.Value)/100;
            
            app.lc1.Value = l1;
            app.lc2.Value= l2;

        end

        % Value changed function: lc1
        function lc1ValueChanged(app, event)
            
            
        end

        % Value changed function: lc2
        function lc2ValueChanged(app, event)
            
            
        end

        % Button pushed function: reasButton
        function reasButtonPushed(app, event)
            a1 = (app.p.Value*app.c.Value)/10000;

            a2 = (app.p.Value*app.a.Value)/10000;

            app.A1.Value= a1;
            app.A2.Value = a2;

        end

        % Button pushed function: ReluctanciasButton
        function ReluctanciasButtonPushed(app, event)
            miu0 =4*pi*1e-7;
            miu = app.miur.Value*miu0;
            rel1 = app.lc1.Value/miu/app.A1.Value;
            rel2 = app.lc2.Value/miu/app.A2.Value;

            relq= rel1+rel2;

            app.Rel1.Value = rel1;
            app.Rel2.Value = rel2;
            app.Relq.Value = relq;


        end

        % Button pushed function: FlujoButton
        function FlujoButtonPushed(app, event)
            f = app.N.Value*app.I.Value;
            flujo = f/app.Relq.Value;

            app.FMM.Value = f;
            app.Flujo.Value = flujo;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.aEditFieldLabel = uilabel(app.Panel);
            app.aEditFieldLabel.Text = 'a';
            app.a = uieditfield(app.Panel, 'numeric');
            app.a.ValueChangedFcn = createCallbackFcn(app, @aValueChanged, true);
            app.bEditFieldLabel = uilabel(app.Panel);
            app.bEditFieldLabel.Text = 'b';
            app.b = uieditfield(app.Panel, 'numeric');
            app.b.ValueChangedFcn = createCallbackFcn(app, @bValueChanged, true);
            app.cEditFieldLabel = uilabel(app.Panel);
            app.cEditFieldLabel.Text = 'c';
            app.c = uieditfield(app.Panel, 'numeric');
            app.c.ValueChangedFcn = createCallbackFcn(app, @cValueChanged, true);
            app.RecorridosButton = uibutton(app.Panel, 'push');
            app.RecorridosButton.ButtonPushedFcn = createCallbackFcn(app, @RecorridosButtonPushed, true);
            app.RecorridosButton.Text = 'Recorridos';
            app.miurLabel = uilabel(app.Panel);
            app.miurLabel.Text = 'miur';
            app.miur = uieditfield(app.Panel, 'numeric');
            app.miurLabel_2 = uilabel(app.Panel);
            app.miurLabel_2.Text = 'N';
            app.N = uieditfield(app.Panel, 'numeric');
            app.miurLabel_3 = uilabel(app.Panel);
            app.miurLabel_3.Text = 'I';
            app.I = uieditfield(app.Panel, 'numeric');
            app.reasButton = uibutton(app.Panel, 'push');
            app.reasButton.ButtonPushedFcn = createCallbackFcn(app, @reasButtonPushed, true);
            app.reasButton.Text = 'Áreas';
            app.FlujoButton = uibutton(app.Panel, 'push');
            app.FlujoButton.ButtonPushedFcn = createCallbackFcn(app, @FlujoButtonPushed, true);
            app.FlujoButton.Text = 'Flujo';
            app.pLabel = uilabel(app.Panel);
            app.pLabel.Text = 'p';
            app.p = uieditfield(app.Panel, 'numeric');
            app.ReluctanciasButton = uibutton(app.Panel, 'push');
            app.ReluctanciasButton.ButtonPushedFcn = createCallbackFcn(app, @ReluctanciasButtonPushed, true);
            app.ReluctanciasButton.Text = 'Reluctancias';
            app.lc1EditFieldLabel = uilabel(app.UIFigure);
            app.lc1EditFieldLabel.Text = 'lc1';
            app.lc1 = uieditfield(app.UIFigure, 'numeric');
            app.lc1.ValueChangedFcn = createCallbackFcn(app, @lc1ValueChanged, true);
            app.lc2EditFieldLabel = uilabel(app.UIFigure);
            app.lc2EditFieldLabel.Text = 'lc2';
            app.lc2 = uieditfield(app.UIFigure, 'numeric');
            app.lc2.ValueChangedFcn = createCallbackFcn(app, @lc2ValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Programa para Calcular el Flujo Magnético interno en el Núcleo Ferromagnético';
            app.A1Label = uilabel(app.UIFigure);
            app.A1Label.Text = 'A1';
            app.A1 = uieditfield(app.UIFigure, 'numeric');
            app.A2Label = uilabel(app.UIFigure);
            app.A2Label.Text = 'A2';
            app.A2 = uieditfield(app.UIFigure, 'numeric');
            app.A2Label_2 = uilabel(app.UIFigure);
            app.A2Label_2.Text = 'Rel1';
            app.Rel1 = uieditfield(app.UIFigure, 'numeric');
            app.A2Label_3 = uilabel(app.UIFigure);
            app.A2Label_3.Text = 'Rel2';
            app.Rel2 = uieditfield(app.UIFigure, 'numeric');
            app.A2Label_4 = uilabel(app.UIFigure);
            app.A2Label_4.Text = 'Relq';
            app.Relq = uieditfield(app.UIFigure, 'numeric');
            app.A2Label_5 = uilabel(app.UIFigure);
            app.A2Label_5.Text = 'FMM';
            app.FMM = uieditfield(app.UIFigure, 'numeric');
            app.A2Label_6 = uilabel(app.UIFigure);
            app.A2Label_6.Text = 'Flujo';
            app.Flujo = uieditfield(app.UIFigure, 'numeric');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'proy11.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end